classdef encontrar_raiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_5                      matlab.ui.container.Panel
        UIAxes_3                     matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        UIAxes_2                     matlab.ui.control.UIAxes
        Panel_3                      matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        Panel_2                      matlab.ui.container.Panel
        GuardarresultadosButton      matlab.ui.control.Button
        EjemploresueltoButton        matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        ToleranciaEditField          matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel     matlab.ui.control.Label
        encontrarraicesLabel         matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        ConocermasButton             matlab.ui.control.Button
        MtodosparaLabel              matlab.ui.control.Label
        IntroducelafuncionEditField  matlab.ui.control.EditField
        IntroducelafuncionEditFieldLabel  matlab.ui.control.Label
        CalcularButton               matlab.ui.control.Button
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        SeleccionaelmetodoDropDown   matlab.ui.control.DropDown
        SeleccionaelmetodoDropDownLabel  matlab.ui.control.Label
        IteracionesmaximasEditField  matlab.ui.control.NumericEditField
        IteracionesmaximasEditFieldLabel  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu_2                       matlab.ui.container.Menu
        Menu2_2                      matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            f = str2sym(app.IntroducelafuncionEditField.Value);
            i = app.IteracionesmaximasEditField.Value;
            e = app.ToleranciaEditField.Value;
            metodo = app.SeleccionaelmetodoDropDown.Value;
            switch metodo
                case "Biseccion"
                    resultados = biseccion(a,b,i,e,f);
                case "Falsa posicion"
                    resultados = falsaposicion(a,b,i,e,f);
                case "Recta secante"
                    resultados = rectasecante(a,b,i,e,f);
                case "Newton-Raphson"
                    resultados = newtonraphson(a,i,e,f);
                case "Punto fijo"
                    resultados = puntofijo(a,i,e,f);
            end

           app.UITable.Data = resultados;
           
           cla(app.UIAxes)
           cla(app.UIAxes_2)
           cla(app.UIAxes_3)
           title(app.UIAxes_2,'Iteracion vs Aproximacion')
           title(app.UIAxes_3,'Iteracion vs Error')
           title(app.UIAxes,'Funcion')
           app.UITable.ColumnFormat = {'short','long','long'};
           vars = symvar(f);
           sol1 = resultados(end,2);
           %Gráfico aproximación - error vs aproximaciones
            %Valor real y aproximación
            xlabel(app.UIAxes_2,'Iteración');
            grid(app.UIAxes_2,"on");
            x1=[0,numel(resultados(:,1))-1];
            y1=[sol1,sol1];
            ylabel(app.UIAxes_2,'Aproximación');
            hold(app.UIAxes_2,'on');
    
            %Aproximación
            plot(app.UIAxes_2,resultados(:,1)',resultados(:,2)','o','LineWidth',2);
    
            %Exacta
            plot(app.UIAxes_2,x1,y1,'--',"Color","r");
            hold(app.UIAxes_2,'off');
            legend(app.UIAxes_2,{'Aproximaciones por metodo numerico','Solución según sistema'},'Location','best');
            
            %Gráfico error
            xlabel(app.UIAxes_3,'Iteración');
            grid(app.UIAxes_3,"on");
            ylabel(app.UIAxes_3,'Error');
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,resultados(:,1)',resultados(:,3)','o','LineWidth',2);
            hold(app.UIAxes_3,'off');
            legend(app.UIAxes_3,{'Error'},'Location','best');
    
            %Gráfica función
            xlabel(app.UIAxes,'Eje X'); 
            ylabel(app.UIAxes,'Eje Y');
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            %Función
            x2=sol1(1)-0.75*abs(sol1(1)):0.01:sol1(1)+0.75*abs(sol1(1));
            y2=double(subs(f,vars(1),x2));
            plot(app.UIAxes,x2,y2,'LineWidth',2);
            plot(app.UIAxes,vpasolve(f,vars(1)),double(subs(f,vars(1),vpasolve(f,vars(1)))),"Color",'r',"Marker","*","MarkerSize",15);
            %Ejes coordenados
            xline(app.UIAxes,0); 
            yline(app.UIAxes,0);
            hold(app.UIAxes,"off");
            legend(app.UIAxes,{'Función','Solución'},'Location','best');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: EjemploresueltoButton
        function EjemploresueltoButtonPushed(app, event)
            % funcion de ejemplo resuelto
            f = str2sym("x^3-cos(x)");
            app.IntroducelafuncionEditField.Value = "x^3-cos(x)";
            app.aEditField.Value =-1;
            app.bEditField.Value = 1;
            app.IteracionesmaximasEditField.Value = 30;
            app.ToleranciaEditField.Value = 0.001;
            app.SeleccionaelmetodoDropDown.Value = "Biseccion";
            resultados = biseccion(-1,1,30,0.001,f);
            app.UITable.Data = resultados;
           
           cla(app.UIAxes)
           cla(app.UIAxes_2)
           cla(app.UIAxes_3)
           title(app.UIAxes_2,'Iteracion vs Aproximacion')
           title(app.UIAxes_3,'Iteracion vs Error')
           title(app.UIAxes,'Funcion')
           app.UITable.ColumnFormat = {'short','long','long'};
           vars = symvar(f);
           sol1 = resultados(end,2);
           %Gráfico aproximación - error vs aproximaciones
            %Valor real y aproximación
            xlabel(app.UIAxes_2,'Iteración');
            grid(app.UIAxes_2,"on");
            x1=[0,numel(resultados(:,1))-1];
            y1=[sol1,sol1];
            ylabel(app.UIAxes_2,'Aproximación');
            hold(app.UIAxes_2,'on');
    
            %Aproximación
            plot(app.UIAxes_2,resultados(:,1)',resultados(:,2)','o','LineWidth',2);
    
            %Exacta
            plot(app.UIAxes_2,x1,y1,'--',"Color","r");
            hold(app.UIAxes_2,'off');
            legend(app.UIAxes_2,{'Aproximaciones por metodo numerico','Solución según sistema'},'Location','best');
            
            %Gráfico error
            xlabel(app.UIAxes_3,'Iteración');
            grid(app.UIAxes_3,"on");
            ylabel(app.UIAxes_3,'Error');
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,resultados(:,1)',resultados(:,3)','o','LineWidth',2);
            hold(app.UIAxes_3,'off');
            legend(app.UIAxes_3,{'Error'},'Location','best');
    
            %Gráfica función
            xlabel(app.UIAxes,'Eje X'); 
            ylabel(app.UIAxes,'Eje Y');
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            %Función
            x2=sol1(1)-0.75*abs(sol1(1)):0.01:sol1(1)+0.75*abs(sol1(1));
            y2=double(subs(f,vars(1),x2));
            plot(app.UIAxes,x2,y2,'LineWidth',2);
            plot(app.UIAxes,vpasolve(f,vars(1)),double(subs(f,vars(1),vpasolve(f,vars(1)))),"Color",'r',"Marker","*","MarkerSize",15);
            %Ejes coordenados
            xline(app.UIAxes,0); 
            yline(app.UIAxes,0);
            hold(app.UIAxes,"off");
            legend(app.UIAxes,{'Función','Solución'},'Location','best');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % funcion del boton de ayuda
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            writematrix(app.UITable.Data,'resultadosEncontrarRaiz.csv')
            msgbox("Los datos se han guardado con exito")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Funcion vs Interpolacion')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.IteracionesmaximasEditFieldLabel = uilabel(app.Panel_2);
            app.IteracionesmaximasEditFieldLabel.Text = 'Iteraciones maximas';
            app.IteracionesmaximasEditField = uieditfield(app.Panel_2, 'numeric');
            app.SeleccionaelmetodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmetodoDropDownLabel.Text = 'Selecciona el metodo';
            app.SeleccionaelmetodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmetodoDropDown.Items = {'Biseccion', 'Newton-Raphson', 'Falsa posicion', 'Recta secante', 'Punto fijo'};
            app.SeleccionaelmetodoDropDown.Value = 'Biseccion';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Tooltip = {'Boton para calcular'};
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncionEditFieldLabel = uilabel(app.Panel_2);
            app.IntroducelafuncionEditFieldLabel.Text = 'Introduce la funcion';
            app.IntroducelafuncionEditField = uieditfield(app.Panel_2, 'text');
            app.MtodosparaLabel = uilabel(app.Panel_2);
            app.MtodosparaLabel.Text = 'Métodos para';
            app.ConocermasButton = uibutton(app.Panel_2, 'push');
            app.ConocermasButton.Tooltip = {'Boton para acceder a la teoria del metodo seleccionado'};
            app.ConocermasButton.Text = 'Conocer mas...';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Tooltip = {'Regresar al inicio'};
            app.Button.Text = '';
            app.encontrarraicesLabel = uilabel(app.Panel_2);
            app.encontrarraicesLabel.Text = 'encontrar raices';
            app.ToleranciaEditFieldLabel = uilabel(app.Panel_2);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.Panel_2, 'numeric');
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon feather-help-circle@2x.png');
            app.Button_2.Tooltip = {'Boton de ayuda'};
            app.Button_2.Text = '';
            app.EjemploresueltoButton = uibutton(app.Panel_2, 'push');
            app.EjemploresueltoButton.ButtonPushedFcn = createCallbackFcn(app, @EjemploresueltoButtonPushed, true);
            app.EjemploresueltoButton.Tooltip = {'Boton para rellenar los campos con un ejemplo resuelto'};
            app.EjemploresueltoButton.Text = 'Ejemplo resuelto';
            app.GuardarresultadosButton = uibutton(app.Panel_2, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'iteracion'; 'x'; 'y'};
            app.UITable.RowName = {};
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_4);
            title(app.UIAxes_2, 'Iteracion vs Aproximacion')
            xlabel(app.UIAxes_2, 'Eje X')
            ylabel(app.UIAxes_2, 'Eje Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_5 = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel_5);
            title(app.UIAxes_3, 'Funcion vs Interpolacion')
            xlabel(app.UIAxes_3, 'Eje X')
            ylabel(app.UIAxes_3, 'Eje Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.Menu);
            app.Menu2_2.Text = 'Menu2';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encontrar_raiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end